classdef PF < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        XoEditFieldLabel            matlab.ui.control.Label
        Interacion0                 matlab.ui.control.EditField
        Image3                      matlab.ui.control.Image
        Image2                      matlab.ui.control.Image
        PolinomioEditFieldLabel_3   matlab.ui.control.Label
        PolinomioEditFieldLabel_2   matlab.ui.control.Label
        CantidadDecimalesEditField  matlab.ui.control.EditField
        CantidadDecimalesLabel      matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        PolinomioEditField          matlab.ui.control.EditField
        PolinomioEditFieldLabel     matlab.ui.control.Label
        CalcularraizButton          matlab.ui.control.Button
        NewtonRaphsonLabel          matlab.ui.control.Label
        UIAxes                      matlab.ui.control.UIAxes
    end

%% ------------------------------------------------------------------------
%--------------------------Funciones---------------------------------------
%--------------------------------------------------------------------------
    
    methods (Access = private)
        
      
    %% Funciones de Abigail
         % Función para validar y obtener el número de decimales
        function numDecimales = obtenerDecimales(app)
            % Obtiene y valida el número de decimales
            numDecimales = str2double(app.CantidadDecimalesEditField.Value);
            
            if isnan(numDecimales) || numDecimales < 0 || mod(numDecimales,1) ~= 0
                uialert(app.UIFigure, 'Ingrese un número entero positivo de decimales', 'Error');
                numDecimales = [];
            end
        end

         % Función de redondeo (puede llamarse múltiples veces)
        function valorRedondeado = redondearValor(~, valorOriginal, numDecimales)
            % Redondea un valor al número especificado de decimales
            valorRedondeado = round(valorOriginal, numDecimales);
        end

        % Función de formateo para que siempre tenga la cantidad de
        % decimales
        function formato = obtenerFormato(~, numDecimales)
            % Crea el string de formato para el número especificado de decimales
            formato = ['%.', num2str(numDecimales), 'f'];
        end
        
      %% Funciones de Hugo
        %Funcion para deribar las funciones
    function funcionDerivada = derivar(app, funcionStr)
        try
            % Limpieza y preparación inicial
            funcionStr = app.prepararFuncion(funcionStr);
            disp('=== Proceso de Derivación ===');
            disp(['Función original: f(x) = ' funcionStr]);
            
            % Intentar diferentes métodos de derivación
            [funcionDerivada, metodo] = app.intentarMetodosDerivacion(funcionStr);
            
            if isempty(funcionDerivada)
                error('No se pudo derivar la función con ningún método');
            end
            
            % Mostrar resultados
            disp(['Método usado: ' metodo]);
            disp(['Derivada obtenida: f''(x) = ' func2str(funcionDerivada)]);
            disp('------------------------------');
            
        catch ME
            uialert(app.UIFigure, ['Error al derivar: ' ME.message], 'Error de derivación');
            funcionDerivada = [];
        end
    end
    
    % Función para preparar la expresión matemática
    function funcionStr = prepararFuncion(~, funcionStr)
        % Limpieza básica
        funcionStr = strrep(strtrim(funcionStr), ' ', '');
        
        % Conversión de formato natural a sintaxis MATLAB
        funcionStr = regexprep(funcionStr, '(\d)([xX])', '$1*$2'); % 3x -> 3*x
        funcionStr = regexprep(funcionStr, '([xX])(\d)', '$1^$2');  % x3 -> x^3
        funcionStr = regexprep(funcionStr, ')(', ')*(');           % )( -> )*(
        funcionStr = regexprep(funcionStr, '(\d)(\()', '$1*$2');   % 3( -> 3*(
        funcionStr = regexprep(funcionStr, '(\))(\d)', '$1*$2');   % )3 -> )*3
    end
    
    % Función que prueba diferentes métodos de derivación
    function [funcionDerivada, metodo] = intentarMetodosDerivacion(app, funcionStr)
        % Lista de métodos a probar (en orden de preferencia)
        metodos = {
            @app.intentarDerivacionSymbolica,   'Derivación simbólica estándar';
            @app.intentarReglaCociente,        'Regla del cociente';
            @app.intentarDerivacionLogaritmica, 'Derivación logarítmica';
            @app.intentarReglaProducto,         'Regla del producto'
        };
        
        % Probar cada método secuencialmente
        for i = 1:size(metodos, 1)
            try
                [funcionDerivada, metodo] = metodos{i,1}(funcionStr);
                if ~isempty(funcionDerivada)
                    return; % Salir si se encontró un método que funciona
                end
            catch
                continue; % Continuar con el siguiente método si falla
            end
        end
        
        % Si ningún método funcionó
        funcionDerivada = [];
        metodo = 'Ningún método funcionó';
    end


    function [funcionDerivada, metodo] = intentarDerivacionSymbolica(~, funcionStr)
        syms x;
        try
            expr = str2sym(funcionStr);
            derivadaSym = diff(expr, x);
            derivadaSym = simplify(derivadaSym, 'Steps', 50, 'Seconds', 5);
            funcionDerivada = str2func(['@(x) ' char(derivadaSym)]);
            metodo = 'Derivación simbólica estándar';
        catch
            error('No se pudo derivar con método simbólico estándar');
        end
    end
    
    % 2. Regla del cociente (para divisiones)
    function [funcionDerivada, metodo] = intentarReglaCociente(~, funcionStr)
        % Verificar si es una división
        if ~contains(funcionStr, '/')
            error('No es una división');
        end
        
        syms x;
        try
            expr = str2sym(funcionStr);
            [numerador, denominador] = numden(expr);
            
            % Aplicar regla del cociente: (f/g)' = (f'g - fg')/g²
            f_prime = diff(numerador, x);
            g_prime = diff(denominador, x);
            
            derivadaSym = (f_prime*denominador - numerador*g_prime) / denominador^2;
            derivadaSym = simplify(derivadaSym, 'Steps', 50, 'Seconds', 5);
            
            funcionDerivada = str2func(['@(x) ' char(derivadaSym)]);
            metodo = 'Regla del cociente';
        catch
            error('Fallo en regla del cociente');
        end
    end
    
    % 3. Derivación logarítmica (para productos/cocientes complejos)
    function [funcionDerivada, metodo] = intentarDerivacionLogaritmica(~, funcionStr)
        syms x positive; % Asumir x positiva para evitar problemas con logaritmos
        try
            expr = str2sym(funcionStr);
            
            % Aplicar logaritmo natural
            ln_expr = log(expr);
            
            % Derivar implícitamente
            derivada_ln = diff(ln_expr, x);
            
            % Resolver para dy/dx
            derivadaSym = expr * derivada_ln;
            derivadaSym = simplify(derivadaSym, 'Steps', 50, 'Seconds', 5);
            
            funcionDerivada = str2func(['@(x) ' char(derivadaSym)]);
            metodo = 'Derivación logarítmica';
        catch
            error('Fallo en derivación logarítmica');
        end
    end
    
    % 4. Regla del producto (para productos múltiples)
    function [funcionDerivada, metodo] = intentarReglaProducto(~, funcionStr)
        % Verificar si es un producto
        if ~contains(funcionStr, '*')
            error('No es un producto');
        end
        
        syms x;
        try
            expr = str2sym(funcionStr);
            
            % Identificar términos del producto
            terminos = children(expr);
            if length(terminos) < 2
                error('No hay suficientes términos para producto');
            end
            
            % Aplicar regla del producto generalizada
            derivadaSym = 0;
            for i = 1:length(terminos)
                % Calcular derivada del i-ésimo término
                termino_deriv = diff(terminos{i}, x);
                
                % Multiplicar por los otros términos
                otros_terminos = terminos;
                otros_terminos(i) = [];
                producto_otros = prod(otros_terminos);
                
                % Sumar a la derivada total
                derivadaSym = derivadaSym + termino_deriv * producto_otros;
            end
            
            derivadaSym = simplify(derivadaSym, 'Steps', 50, 'Seconds', 5);
            funcionDerivada = str2func(['@(x) ' char(derivadaSym)]);
            metodo = 'Regla del producto';
        catch
            error('Fallo en regla del producto');
        end
    end       


    end   

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalcularraizButton
        function CalcularraizButtonPushed(app, event)
%% -------------------------------------------------------------------------
%----------------------Boton Calcular-------------------------------------
%-------------------------------------------------------------------------

%% Metodos de Gustavo
try
    % 1. Validar que todos los campos estén completos
    camposFaltantes = {};
    
    % Verificar función
    funcionStr = app.PolinomioEditField.Value;
    if isempty(funcionStr)
        camposFaltantes{end+1} = 'Función matemática';
    end
    
    % Verificar valor inicial (Interacion0)
    x0Str = app.Interacion0.Value;
    if isempty(x0Str)
        camposFaltantes{end+1} = 'Valor inicial (x0)';
    end
    
    % Verificar decimales
    decimalesStr = app.CantidadDecimalesEditField.Value;
    if isempty(decimalesStr)
        camposFaltantes{end+1} = 'Cantidad de decimales';
    end
    
    % Mostrar error si faltan campos
    if ~isempty(camposFaltantes)
        mensaje = sprintf('Faltan los siguientes campos:\n\n• %s', strjoin(camposFaltantes, '\n• '));
        uialert(app.UIFigure, mensaje, 'Campos incompletos', 'Icon', 'error');
        return;
    end
    
    % 2. Validar número de decimales
    numDecimales = app.obtenerDecimales();
    if isempty(numDecimales)
        return;
    end
    
    % 3. Validar valor inicial (x0)
    x0 = str2double(x0Str);
    if isnan(x0)
        uialert(app.UIFigure, 'El valor inicial (x0) debe ser un número válido', 'Error en valor inicial');
        return;
    end
    
    % 4. Procesar la función matemática (versión compatible con compiler)
    funcionStr = strtrim(funcionStr);

    % Verificar caracteres no permitidos
    caracteresInvalidos = regexp(funcionStr, '[^0-9a-zA-ZxX\+\-\*\/\^\.\(\)\s]', 'match');
    if ~isempty(caracteresInvalidos)
        uialert(app.UIFigure, sprintf('Caracteres no permitidos en la función: %s', unique(caracteresInvalidos)), 'Error');
        return;
    end

    % Formatear expresiones implícitas
    funcionStr = regexprep(funcionStr, '(\d)([xX])', '$1*$2');  % 3x -> 3*x
    funcionStr = regexprep(funcionStr, '([xX])(\d)', '$1^$2');  % x2 -> x^2
    funcionStr = regexprep(funcionStr, ')(', ')*(');            % )( -> )*(
    funcionStr = regexprep(funcionStr, '(\d)(\()', '$1*$2');    % 3( -> 3*(
    funcionStr = regexprep(funcionStr, '(\))(\d)', '$1*$2');    % )3 -> )*3

    % Validar y convertir función (sin Symbolic Math Toolbox)
    try
        % Crear función anónima directamente (evitando sym)
        funcionHandle = str2func(['@(x) ' funcionStr]);
        
        % Probar evaluación
        valorPrueba = funcionHandle(x0);
        if isnan(valorPrueba) || isinf(valorPrueba)
            error('La función devolvió NaN o Inf en el punto inicial');
        end
    catch
        uialert(app.UIFigure, 'La función ingresada no es válida o no puede evaluarse', 'Error de función');
        return;
    end

    % 5. Calcular derivada numérica (en lugar de simbólica)
    try
        h = 10^(-6); % Paso para derivación numérica
        f_derivada = @(x) (funcionHandle(x+h) - funcionHandle(x-h))/(2*h);
        
        % Probar derivada
        valorDerivada = f_derivada(x0);
        if isnan(valorDerivada) || isinf(valorDerivada)
            error('La derivada no pudo calcularse en el punto inicial');
        end
    catch
        uialert(app.UIFigure, 'Error al calcular la derivada numérica', 'Error de derivación');
        return;
    end
    
    %% Mostrar en consola
    disp('-----------------------------------');
    disp(['Función original: f(x) = ' funcionStr]);
    disp('Derivada calculada numéricamente');
    disp('-----------------------------------');

    % Parámetros del método
    tolerancia = 10^(-numDecimales);
    iterMax = 100;
    iter = 0;
    x_anterior = x0;

    % Almacenar iteraciones para tabla y gráfico
    iteraciones = [];
    
    while true
        iter = iter + 1;
        f_x = funcionHandle(x_anterior);
        df_x = f_derivada(x_anterior);

        if df_x == 0
            uialert(app.UIFigure, 'La derivada se hizo cero. No se puede continuar.', 'Error');
            return;
        end

        x_nuevo = x_anterior - f_x / df_x;
        iteraciones = [iteraciones; x_anterior, f_x, df_x, x_nuevo];

        disp(['Iteración ' num2str(iter) ': x = ' num2str(x_nuevo)]);

        if abs(x_nuevo - x_anterior) < tolerancia
            break;
        end

        if iter >= iterMax
            uialert(app.UIFigure, 'Se alcanzó el número máximo de iteraciones sin convergencia.', 'Advertencia');
            break;
        end

        x_anterior = x_nuevo;
    end

    % Redondear resultado final
    resultadoRedondeado = app.redondearValor(x_nuevo, numDecimales);
    formato = app.obtenerFormato(numDecimales);
    resultadoFormateado = sprintf(formato, resultadoRedondeado);

    % Mostrar en interfaz
    app.PolinomioEditFieldLabel_3.Text = resultadoFormateado;

    disp('===================================');
    disp(['Raíz aproximada: ' resultadoFormateado]);
    disp(['Total de iteraciones: ' num2str(iter)]);
    disp('===================================');
    
    %%-----------------------------------------------------------------
    % Tabla (versión numérica)
    resultados = cell(size(iteraciones,1), 6);
    for i = 1:size(iteraciones,1)
        resultados{i,1} = i; % No. iteración
        resultados{i,2} = sprintf(formato, iteraciones(i,1)); % Xn
        resultados{i,3} = sprintf(formato, iteraciones(i,2)); % f(x)
        resultados{i,4} = sprintf(formato, iteraciones(i,3)); % f'(x)
        resultados{i,5} = sprintf('%s - (%s/%s)', ...
            sprintf(formato, iteraciones(i,1)), ...
            sprintf(formato, iteraciones(i,2)), ...
            sprintf(formato, iteraciones(i,3))); % Fórmula
        resultados{i,6} = sprintf(formato, iteraciones(i,4)); % Resultado
    end

    app.UITable.Data = resultados;
    app.UITable.ColumnName = {'No.', 'Xn', 'f(x)', 'f''(x)', 'Xn - (f(x)/f''(x))', 'Resultado'};

    % ---------------------------------------------------------
    % Gráfica (versión numérica)
    try
        cla(app.UIAxes);
        
        % Definir rango de graficación
        x_min = min(iteraciones(:,1)) - 1;
        x_max = max(iteraciones(:,1)) + 1;
        x_vals = linspace(x_min, x_max, 500);
        
        % Evaluar función (punto por punto para evitar problemas)
        y_vals = arrayfun(funcionHandle, x_vals);
        
        % Graficar función
        plot(app.UIAxes, x_vals, y_vals, 'b', 'LineWidth', 2);
        hold(app.UIAxes, 'on');
        
        % Graficar puntos de iteración
        plot(app.UIAxes, iteraciones(:,1), arrayfun(funcionHandle, iteraciones(:,1)), ...
            'ko', 'MarkerFaceColor', 'g', 'MarkerSize', 6);
        
        % Línea y=0
        plot(app.UIAxes, [x_min x_max], [0 0], 'k--');
        
        grid(app.UIAxes, 'on');
        xlabel(app.UIAxes, 'x');
        ylabel(app.UIAxes, 'f(x)');
        legend(app.UIAxes, {'f(x)', 'Puntos iteración'}, 'Location', 'best');
        title(app.UIAxes, 'Método de Newton-Raphson');
        
        hold(app.UIAxes, 'off');
    catch grafError
        uialert(app.UIFigure, ['Error al graficar: ' grafError.message], 'Error de gráfica');
    end
    
catch ME
    errorMsg = sprintf('Error inesperado:\n\n%s\n\nPor favor verifique los datos.', ME.message);
    uialert(app.UIFigure, errorMsg, 'Error crítico', 'Icon', 'error');
    disp(getReport(ME, 'extended', 'hyperlinks', 'off'));
end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.NewtonRaphsonLabel = uilabel(app.UIFigure);
            app.NewtonRaphsonLabel.Text = 'Newton Raphson';
            app.CalcularraizButton = uibutton(app.UIFigure, 'push');
            app.CalcularraizButton.ButtonPushedFcn = createCallbackFcn(app, @CalcularraizButtonPushed, true);
            app.CalcularraizButton.Text = 'Calcular raiz';
            app.PolinomioEditFieldLabel = uilabel(app.UIFigure);
            app.PolinomioEditFieldLabel.Text = 'Polinomio:';
            app.PolinomioEditField = uieditfield(app.UIFigure, 'text');
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'No.'; 'Xn'; 'f(x)'; 'f''(x)'; 'Xn - (f(x)/f''(x))'; 'resultado'};
            app.UITable.RowName = {};
            app.CantidadDecimalesLabel = uilabel(app.UIFigure);
            app.CantidadDecimalesLabel.Text = 'Cantidad Decimales:';
            app.CantidadDecimalesEditField = uieditfield(app.UIFigure, 'text');
            app.PolinomioEditFieldLabel_2 = uilabel(app.UIFigure);
            app.PolinomioEditFieldLabel_2.Text = 'La raíz del polinomio es:';
            app.PolinomioEditFieldLabel_3 = uilabel(app.UIFigure);
            app.PolinomioEditFieldLabel_3.Text = '';
            app.Image2 = uiimage(app.UIFigure);
            app.Image2.ImageSource = fullfile(pathToMLAPP, 'UMGLogo.png');
            app.Image3 = uiimage(app.UIFigure);
            app.Image3.ImageSource = fullfile(pathToMLAPP, 'FamKonLogo.png');
            app.Interacion0 = uieditfield(app.UIFigure, 'text');
            app.XoEditFieldLabel = uilabel(app.UIFigure);
            app.XoEditFieldLabel.Text = 'Xo = ';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PF

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end